classdef AudioAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SelectfilesMenu                 matlab.ui.container.Menu
        SelectoutoutfolderMenu          matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LoadaudioButton                 matlab.ui.control.Button
        NumberofsyllablesEditField      matlab.ui.control.NumericEditField
        NumberofsyllablesEditFieldLabel  matlab.ui.control.Label
        HeatmapplotButton               matlab.ui.control.Button
        LineplotButton                  matlab.ui.control.Button
        NumberofloadedaudiofilesEditField  matlab.ui.control.NumericEditField
        NumberofloadedaudiofilesEditFieldLabel  matlab.ui.control.Label
        Button6                         matlab.ui.control.Button
        Button5                         matlab.ui.control.Button
        GetaudiosegmentsButton          matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        NumberofselectedfilesEditField  matlab.ui.control.NumericEditField
        NumberofselectedfilesEditFieldLabel  matlab.ui.control.Label
    end


    properties (Access = private)

        normalize_method = 1; % Description
        obj_list = {audioClip} % Description
    end

    properties (Access = public)
        file_selector = struct('file_list',[],'valid_flag',false, 'last_source_folder','') % Description
        use_folder_tree = false % flag to determine weather to put all files in one folder or use sourse folder tree
        save_folder = '' % Destination folder
        audio_segments = {} % Description
        extended_table
    end

    methods (Access = private)

        function update_num_selected_files(app)
            n_selected_files = height(app.file_selector.file_list);

            app.NumberofselectedfilesEditField.Value = n_selected_files;
        end
        
        function allseg = get_all_audio_segments(app)
            allseg_func = @(obj) getAllSegments(obj);
            allseg = cellfun(allseg_func, app.obj_list,'UniformOutput',false);    
        end
        
        function allseg = get_psd_app(app)
            allseg = {};
            normalize_method_ = app.normalize_method;
            
            
             if isempty(normalize_method_)
                normalize_method_ = 1;
             end
             if isempty(app.obj_list)
                 return
             end
            allseg_func = @(obj) get_psd(obj,app.audio_segments,normalize_method_);
            allseg = cellfun(allseg_func, app.obj_list,'UniformOutput',false);  
            allseg = vertcat(allseg{:});
        end

        function [axout,out_mat] = plot_psd_mean(app)
            
            if isempty(app.audio_segments)
                  app.audio_segments = app.get_all_audio_segments();
            end
            allseg = app.audio_segments;
            if isempty(allseg)
                disp('No audio files to plot')
                return
            end
            allseg = app.get_psd_app();
            
            ax = app.UIAxes;
            cla(ax);
            mean_vec = mean(allseg,1);
            std_vec = std(allseg,1);
            x = 1:numel(mean_vec);


            x2 = [x, fliplr(x)];
            inBetween = [mean_vec-std_vec, fliplr(mean_vec+std_vec)];
            fill(ax,x2, inBetween, 'g','FaceAlpha',0.1);
            hold(ax,'on')
            plot(ax,x,mean_vec)
            hold(ax,'off')

            %             ax.YLim = [0 125];
            ax.XLim = [0 125];
            ax.XLabel.String = 'Frequency (kHz)';
            ax.YLabel.String = 'Relative Amplitue (A.U.)';
            ax.YLimMode = 'auto';
            ax.FontName = 'Times New Roman';
            ax.FontSize = 12;
            

            if nargout>0
                axout = ax;
            end
            if nargout>1
                out_mat = allseg;
            end
        end
        
        function [axout,out_mat]  = plot_psd_surface(app)
             if isempty(app.audio_segments)
                  app.audio_segments = app.get_all_audio_segments();
            end
            allseg = app.audio_segments;
            if isempty(allseg)
                disp('No audio files to plot')
                return
            end
            allseg = app.get_psd_app();
            
            ax = app.UIAxes;
            cla(ax);
            surface(ax,allseg','EdgeColor',"none")
            ax.XLabel.String = 'Call Number';
            ax.YLabel.String = 'Frequency (kHz)';
            ax.FontName = 'Times New Roman';
            ax.FontSize = 12;
            ax.YLim = [0 125];
            if nargout>0
               axout = ax;
            end
            if nargout>1
               out_mat = allseg;
            end


        end
        
        function load_audioClip_objects(app,get_empties_flag)
            if ~exist('get_empties_flag','var')||isempty(get_empties_flag)
                get_empties_flag = false;
            end
             file_list = app.file_selector.file_list;
            if isempty(file_list)
                disp('No files were selected to load')
                return
            end
            obj_list_ ={};
            n_files = height(file_list);
            for k = 1:n_files
                try
                f_path = file_list{k,'filepath'}{:};
                obj = matfile(f_path);
                obj = obj.obj;
                if height(obj.roiTable)==0 && ~get_empties_flag
                     disp('The obj doesnt contain any vocalizations file\n %s',f_path)
                    continue
                end
                obj.setAudioFile(1);
                obj_list_ = [obj_list_;obj]; %#ok
                catch ME
                   fprintf('Unable to load audio file\n %s\n',f_path) 
                   fprintf('Reason: %s\n',ME.message)
                end
            end
            
            app.obj_list = num2cell(obj_list_);
        end
    end
    
    
    methods (Access = public)
        
        function results = get_extended_roi_table(app)
            obj_list_ = app.obj_list;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.save_folder = fullfile(pwd,'output');
            obj = audioClip;
            app.extended_table = obj.extended_roi_table;
        end

        % Menu selected function: SelectfilesMenu
        function SelectfilesMenuSelected(app, event)
            h = FileSelectorApp(app);
            waitfor(h); % This will freeze you app until the FileSelectorApp is closed
            app.update_num_selected_files()
        end

        % Menu selected function: SelectoutoutfolderMenu
        function SelectoutoutfolderMenuSelected(app, event)
            fig = uifigure('Position',[680, 558, 300, 300]);
            uilabel(fig,"Text",'Save Folder Path:', ...
                "Position",[10,260,200,50]);
            tlabel = uilabel(fig,"Text",app.save_folder,'WordWrap','on', ...
                "Position",[10,210,300,50]);

            uibutton(fig, 'push','Text', 'Select save folder','ButtonPushedFcn',  @(btn,event) btnPushed(btn,tlabel,app) ...
                ,'Position',[50,110,200,50]);

            uibutton(fig, 'state','Text', 'Use folder tree','Value',false,'ValueChangedFcn',  @(btn,event) btnValueChanged(btn,app) ...
                ,'Position',[50,60,200,50]);

            uibutton(fig, 'push','Text', 'Close','ButtonPushedFcn',  @(btn,event) closebtnPushed(btn,fig) ...
                ,'Position',[50,10,200,50]);

            waitfor(fig)

            function btnValueChanged(btn,app)
                value = btn.Value;
                app.use_folder_tree = value;
            end
            function btnPushed(~,tlabel,app)
                selpath = uigetdir(app.save_folder,'Select save folder');
                if selpath
                    app.save_folder = selpath;
                    tlabel.Text = app.save_folder;
                end
            end
            function closebtnPushed(~,fig)
                delete(fig)
            end

        end

        % Button pushed function: HeatmapplotButton
        function HeatmapplotButtonPushed(app, event)
            plot_psd_surface(app)
        end

        % Button pushed function: LoadaudioButton
        function LoadaudioButtonPushed(app, event)
             app.load_audioClip_objects(false)   
             app.NumberofloadedaudiofilesEditField.Value = length(app.obj_list);
        end

        % Button pushed function: GetaudiosegmentsButton
        function GetaudiosegmentsButtonPushed(app, event)
            audio_segments_ = app.get_all_audio_segments();
            app.audio_segments =vertcat(audio_segments_{:});
            app.NumberofsyllablesEditField.Value = size(app.audio_segments,1);
        end

        % Button pushed function: LineplotButton
        function LineplotButtonPushed(app, event)
            plot_psd_mean(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SelectfilesMenu = uimenu(app.FileMenu);
            app.SelectfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectfilesMenuSelected, true);
            app.SelectfilesMenu.Text = 'Select files';
            app.SelectoutoutfolderMenu = uimenu(app.FileMenu);
            app.SelectoutoutfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectoutoutfolderMenuSelected, true);
            app.SelectoutoutfolderMenu.Text = 'Select outout folder';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.NumberofselectedfilesEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofselectedfilesEditFieldLabel.Text = 'Number of selected files';
            app.NumberofselectedfilesEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofselectedfilesEditField.Editable = 'off';
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GetaudiosegmentsButton = uibutton(app.GridLayout, 'push');
            app.GetaudiosegmentsButton.ButtonPushedFcn = createCallbackFcn(app, @GetaudiosegmentsButtonPushed, true);
            app.GetaudiosegmentsButton.Text = 'Get audio segments';
            app.Button5 = uibutton(app.GridLayout, 'push');
            app.Button5.Text = 'Button5';
            app.Button6 = uibutton(app.GridLayout, 'push');
            app.Button6.Text = 'Button6';
            app.NumberofloadedaudiofilesEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofloadedaudiofilesEditFieldLabel.Text = 'Number of loaded audio files';
            app.NumberofloadedaudiofilesEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofloadedaudiofilesEditField.Limits = [0 Inf];
            app.NumberofloadedaudiofilesEditField.Editable = 'off';
            app.LineplotButton = uibutton(app.GridLayout, 'push');
            app.LineplotButton.ButtonPushedFcn = createCallbackFcn(app, @LineplotButtonPushed, true);
            app.LineplotButton.Text = 'Line plot';
            app.HeatmapplotButton = uibutton(app.GridLayout, 'push');
            app.HeatmapplotButton.ButtonPushedFcn = createCallbackFcn(app, @HeatmapplotButtonPushed, true);
            app.HeatmapplotButton.Text = 'Heatmap plot';
            app.NumberofsyllablesEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofsyllablesEditFieldLabel.Text = 'Number of syllables';
            app.NumberofsyllablesEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofsyllablesEditField.Limits = [0 Inf];
            app.NumberofsyllablesEditField.Editable = 'off';
            app.LoadaudioButton = uibutton(app.GridLayout, 'push');
            app.LoadaudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadaudioButtonPushed, true);
            app.LoadaudioButton.Text = 'Load audio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end