classdef FileSelectorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        class_name_text             matlab.ui.control.EditField
        ClassnameEditFieldLabel     matlab.ui.control.Label
        matfile_flag                matlab.ui.control.StateButton
        search_file_filter          matlab.ui.control.EditField
        SearchfilterEditFieldLabel  matlab.ui.control.Label
        clear_loaded                matlab.ui.control.Button
        Include_subfolders_state    matlab.ui.control.StateButton
        refresh_folded              matlab.ui.control.Button
        display_area                matlab.ui.control.TextArea
        select_all_files_button     matlab.ui.control.Button
        unselect_button             matlab.ui.control.Button
        source_folder_label         matlab.ui.control.Label
        export_button               matlab.ui.control.Button
        select_file_button          matlab.ui.control.Button
        set_source_folder_button    matlab.ui.control.Button
        selected_file_list_listbox  matlab.ui.control.ListBox
        loaded_file_list_listbox    matlab.ui.control.ListBox
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end


    properties (Access = private)
        current_folder = pwd % Present folder
        name_list_tab = []; % list of loaded file names before selection
        selected_name_list_tab = []; % list of selecet file names
        
        display_txt = '' % Description
        
        hOuterApp = [] % Description
    end
    
    properties (Access = public)
        app_output = struct( ...
            'file_list',[], ...
            'valid_flag',false, ...
            'last_source_folder','') % Connect handle to outer app here 
    end

    methods (Access = private)

        function [val_falg, name_list] = get_variables_names_in_folder(app,folder_name)

            %Default value
            name_list = cell2table({'',[]},'VariableNames',{'filepath','variableInd'});
            val_falg = false;
            txt_filter = app.search_file_filter.Value;
            %Get dir info
            try
            if app.Include_subfolders_state.Value
                file_list = dir(fullfile(folder_name,'**',txt_filter));
            else
                file_list = dir(fullfile(folder_name,txt_filter));    
            end
            catch ME
               app.print('Unable to load files, check the filter') 
               disp(ME.message)
               return
            end
            % remove folders
            file_list=file_list([file_list.isdir]==false);
            file_list=file_list(~ismember({file_list.name},{'.','..'}));


            num_files = size(file_list,1);

            %If there are no files, display a warning
            if ~num_files
                update_counts(app,folder_name)
                return
            end


            %Check for each file if they containg a variable of class
            %audioClip

            %Convert names to cell
            c = ([{file_list.name}',{file_list.folder}']);
            if app.matfile_flag.Value
                
            
            %Get reading function
            k = @(fname,fpath) matfile(fullfile(fpath,fname));
            %read all files
            matfile_list = cellfun(k,c(:,1),c(:,2),'UniformOutput',false);

            %function to get variable class
            getClass = @(mfile) {whos(mfile).class}';
            var_class = app.class_name_text.Value;
            if isempty(var_class)
                var_class = 'double';
            end
            for ifile = 1:num_files
                this_mat = matfile_list{ifile};
                %If there is more than one load only the first one
                ind_var = find(contains(getClass(this_mat),var_class),1);

                %if there are no variable of class audioclip, ignor the
                %file
                if ~isempty(ind_var)
                    name_list = [name_list;{this_mat.Properties.Source,ind_var}];%#ok;
                end

            end
               %Remove placeholder
            if isempty(name_list.filepath{1,1})
                name_list(1,:) = [];
            end
            else
                for ifile = 1:num_files
                    this_path = fullfile(c{ifile,2},c{ifile,1});
                    %file
                    name_list = [name_list;{this_path,1}];%#ok;


                end
               %Remove placeholder
            if isempty(name_list.filepath{1,1})
                name_list(1,:) = [];
            end
                
            end
         

            if ~isempty(name_list)
                val_falg = true;
            end
            
           
        end
        function update_counts(app,folder_name)
            
            if ~exist('folder_name','var')||isempty(folder_name)
                folder_name = app.source_folder_label.Text;
            end
            
            num_files_loaded = size(app.loaded_file_list_listbox.Items,2);
            num_files_selected = size(app.selected_file_list_listbox.Items,2);
            if (num_files_loaded + num_files_selected) == 0
                %no files were loaded or selected
                txt =  sprintf('No files in this directory:\n%s\nPlease select a different folder',folder_name);
            else
                txt = sprintf('Selected %d file of %d files',num_files_selected, num_files_loaded);
                
            end
            print(app, txt)
            
            
        end

        function update_file_list(~,hfile_list,name_list)
            %Read folder and get names of file with audioClip variable in
          
            %Repack variable
            joincell = @(a,b) {a,b};
            
            inds = name_list.variableInd;
            if ~iscell(inds)
                inds = num2cell(inds);
            end
            name_list_reshaped = cellfun(joincell,name_list.filepath,inds,'UniformOutput',false);

            hfile_list.Items = name_list.filepath;
            hfile_list.ItemsData = name_list_reshaped;

            hfile_list.Value = {};

        end


        function results = create_table(~)
            %If the items is empty, create a table
            results = cell2table({{''},{[]}},'VariableNames',{'filepath','variableInd'});
            results(1,:) = [];
        end

        function new_list = remove_names_from_list(~,source_list,names_to_remove)
            [~,ia] = setdiff(source_list.filepath,names_to_remove.filepath);

            new_list = source_list(ia,:);
        end

        function tabout = fix_table(app,items)

            tabout = app.create_table();
            filepath = items(:,1) ;
            variableInd = items(:,2);
            variableInd = num2cell(variableInd);
            tabout = [tabout;[filepath,variableInd]];
        end

        function update_all_file_lists(app)
            update_file_list(app,app.loaded_file_list_listbox,app.name_list_tab)
            update_file_list(app,app.selected_file_list_listbox,app.selected_name_list_tab)
            if ~isempty(app.app_output)
                app.app_output.file_list = app.selected_name_list_tab;
            end
            update_counts(app)
        end

        function update_source_folder(app,dirname,clear_flag)
            
            %if true, reset selection
            if ~exist('clear_flag','var')
                clear_flag = false;
            end
            
            if clear_flag
                app.reset_file_lists()
            end
            
            if dirname
                app.source_folder_label.Text = dirname;
                [val_flag,name_list] = get_variables_names_in_folder(app,dirname);
                if val_flag
                    app.name_list_tab = name_list;
                    update_file_list(app,app.loaded_file_list_listbox,name_list)
                end

                if ~isempty(app.app_output)
                    app.app_output.last_source_folder = dirname;
                end
            end
            app.update_counts(dirname)

        end


        function print(app,msg,~)

%             if ~exist('location','var')||isempty(location)
%                 location = 'app';
%             end
            app.display_area.Value = msg;
        end
        
        function reset_file_lists(app)
            app.name_list_tab = create_table(app);
            app.selected_name_list_tab = create_table(app);
            app.update_all_file_lists()
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hOuterApp)
            source_dirname = pwd;
            
            if nargin>1
                app.hOuterApp = hOuterApp;
                app.app_output = hOuterApp.file_selector;
                %Get last source folder - cannot be empty
                source_dirname_ = app.app_output.last_source_folder;
                if ~isempty(source_dirname_)
                    source_dirname = source_dirname_;
                end


            end
            reset_file_lists(app)
            update_source_folder(app,source_dirname);
            
            %
            %             app.name_list_tab = create_table(app);
            %             app.selected_name_list_tab = create_table(app);
        end

        % Button pushed function: set_source_folder_button
        function set_source_folder_buttonButtonPushed(app, event)
            dirname = uigetdir;
            figure(app.UIFigure) %prevent window from minimizing
            update_source_folder(app,dirname);
        end

        % Button pushed function: select_file_button
        function select_file_buttonButtonPushed(app, event)
            %Transfer selected files
            selected_items = app.loaded_file_list_listbox.Value;
            if isempty(selected_items)
                return
            end

            selected_items = vertcat(selected_items{:});
            selected_items = fix_table(app,selected_items);

            app.selected_name_list_tab = [app.selected_name_list_tab;selected_items];
            app.name_list_tab = app.remove_names_from_list(app.name_list_tab,app.selected_name_list_tab);

            update_all_file_lists(app)
        end

        % Button pushed function: unselect_button
        function unselect_buttonButtonPushed(app, event)
            %Transfer selected files
            selected_items = app.selected_file_list_listbox.Value;
            if isempty(selected_items)
                return
            end

            selected_items = vertcat(selected_items{:});
            selected_items = fix_table(app,selected_items);
            %**********************************
            app.name_list_tab = [app.name_list_tab;selected_items];

            app.selected_name_list_tab = app.remove_names_from_list(app.selected_name_list_tab,selected_items);
            %**********************************
            update_all_file_lists(app)
        end

        % Button pushed function: select_all_files_button
        function select_all_files_buttonButtonPushed(app, event)
            if isempty(app.loaded_file_list_listbox.Items)
                return
            end
            app.loaded_file_list_listbox.Value = app.loaded_file_list_listbox.ItemsData;
        end

        % Button pushed function: export_button
        function export_buttonButtonPushed(app, event)
            if ~isempty(app.hOuterApp)
                app.app_output.valid_flag = true;
                app.hOuterApp.file_selector = app.app_output;
            else
                out_var = app.app_output;
                export2wsdlg({'selected_files'},{'out_var'},{out_var})
            end


            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.app_output)
                app.app_output.valid_flag = false;
            end
            
            delete(app)
        end

        % Button pushed function: clear_loaded
        function clear_loadedButtonPushed(app, event)
             %Transfer selected files
            selected_items = app.loaded_file_list_listbox.Value;
            if isempty(selected_items)
                return
            end

            selected_items = vertcat(selected_items{:});
            selected_items = fix_table(app,selected_items);

            %app.selected_name_list_tab = [app.selected_name_list_tab;selected_items];
            app.name_list_tab = app.remove_names_from_list(app.name_list_tab,selected_items);

            update_all_file_lists(app)
        end

        % Button pushed function: refresh_folded
        function refresh_foldedButtonPushed(app, event)
            update_source_folder(app,app.source_folder_label.Text,true)
        end

        % Value changed function: matfile_flag
        function matfile_flagValueChanged(app, event)
            value = app.matfile_flag.Value;
            if value
                filter_val = app.search_file_filter.Value;
                filter_val = split(filter_val,'.');
                filter_val = [filter_val{1},'*.mat'];
                app.search_file_filter.Value = filter_val;
            end
        end

        % Value changed function: class_name_text
        function class_name_textValueChanged(app, event)
            value = app.class_name_text.Value;
            if ~isvarname(value)
                app.class_name_text.Value = '';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.loaded_file_list_listbox = uilistbox(app.GridLayout);
            app.loaded_file_list_listbox.Items = {};
            app.loaded_file_list_listbox.Multiselect = 'on';
            app.loaded_file_list_listbox.Value = {};
            app.selected_file_list_listbox = uilistbox(app.GridLayout);
            app.selected_file_list_listbox.Items = {};
            app.selected_file_list_listbox.Multiselect = 'on';
            app.selected_file_list_listbox.Value = {};
            app.set_source_folder_button = uibutton(app.GridLayout, 'push');
            app.set_source_folder_button.ButtonPushedFcn = createCallbackFcn(app, @set_source_folder_buttonButtonPushed, true);
            app.set_source_folder_button.Text = 'Select folder';
            app.select_file_button = uibutton(app.GridLayout, 'push');
            app.select_file_button.ButtonPushedFcn = createCallbackFcn(app, @select_file_buttonButtonPushed, true);
            app.select_file_button.Text = 'Select ->';
            app.export_button = uibutton(app.GridLayout, 'push');
            app.export_button.ButtonPushedFcn = createCallbackFcn(app, @export_buttonButtonPushed, true);
            app.export_button.Text = 'Export';
            app.source_folder_label = uilabel(app.GridLayout);
            app.source_folder_label.Text = 'Folder path';
            app.unselect_button = uibutton(app.GridLayout, 'push');
            app.unselect_button.ButtonPushedFcn = createCallbackFcn(app, @unselect_buttonButtonPushed, true);
            app.unselect_button.Text = '<- Unselect';
            app.select_all_files_button = uibutton(app.GridLayout, 'push');
            app.select_all_files_button.ButtonPushedFcn = createCallbackFcn(app, @select_all_files_buttonButtonPushed, true);
            app.select_all_files_button.Text = 'Select all';
            app.display_area = uitextarea(app.GridLayout);
            app.display_area.Editable = 'off';
            app.display_area.Value = {'Select files to load'};
            app.refresh_folded = uibutton(app.GridLayout, 'push');
            app.refresh_folded.ButtonPushedFcn = createCallbackFcn(app, @refresh_foldedButtonPushed, true);
            app.refresh_folded.Text = 'Refresh folder';
            app.Include_subfolders_state = uibutton(app.GridLayout, 'state');
            app.Include_subfolders_state.Text = 'Include subfolders';
            app.clear_loaded = uibutton(app.GridLayout, 'push');
            app.clear_loaded.ButtonPushedFcn = createCallbackFcn(app, @clear_loadedButtonPushed, true);
            app.clear_loaded.Text = 'Clear loaded';
            app.SearchfilterEditFieldLabel = uilabel(app.GridLayout);
            app.SearchfilterEditFieldLabel.Text = 'Search filter';
            app.search_file_filter = uieditfield(app.GridLayout, 'text');
            app.search_file_filter.Value = '*.mat';
            app.matfile_flag = uibutton(app.GridLayout, 'state');
            app.matfile_flag.ValueChangedFcn = createCallbackFcn(app, @matfile_flagValueChanged, true);
            app.matfile_flag.Text = 'Matfiles';
            app.matfile_flag.Value = true;
            app.ClassnameEditFieldLabel = uilabel(app.GridLayout);
            app.ClassnameEditFieldLabel.Text = 'Class name';
            app.class_name_text = uieditfield(app.GridLayout, 'text');
            app.class_name_text.ValueChangedFcn = createCallbackFcn(app, @class_name_textValueChanged, true);
            app.class_name_text.Value = 'audioClip';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.source_folder_label.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FileSelectorApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end