classdef load_and_save_excel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        SavedeepphenptypingfilesCheckBox  matlab.ui.control.CheckBox
        SaveexcelfilesCheckBox       matlab.ui.control.CheckBox
        ResetAudioFolderButton       matlab.ui.control.StateButton
        audio_folder_label           matlab.ui.control.Label
        set_source_folder_button_2   matlab.ui.control.Button
        use_same_save_folder_button  matlab.ui.control.Button
        save_folder_label            matlab.ui.control.Label
        set_save_folder_button       matlab.ui.control.Button
        select_all_files_button      matlab.ui.control.Button
        unselect_button              matlab.ui.control.Button
        source_folder_label          matlab.ui.control.Label
        run_button                   matlab.ui.control.Button
        select_file_button           matlab.ui.control.Button
        set_source_folder_button     matlab.ui.control.Button
        selected_file_list_listbox   matlab.ui.control.ListBox
        file_list_listbox            matlab.ui.control.ListBox
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end


    properties (Access = private)
        current_folder = pwd % Present folder
        save_folder = pwd;
        name_list_tab = [];
        selected_name_list_tab = [];
        audio_folder = [];
        hHybridMuse
    end

    methods (Access = private)

        function [val_falg, name_list] = get_variables_names_in_folder(~,folder_name)

            %Default value
            name_list = cell2table({'',[]},'VariableNames',{'filepath','variableInd'});
            val_falg = false;

            %Get dir info
            file_list = dir([folder_name,'/*.mat']);


            num_files = size(file_list,1);

            %If there are no files, send a warning and return
            if ~num_files
                emptyFolderWarning(folder_name)
                return
            end

            %Check for each file if they containg a variable of class
            %audioClip

            %Convert names to cell
            c = ([{file_list.name}',{file_list.folder}']);
            %Get reading function
            k = @(fname,fpath) matfile(fullfile(fpath,fname));
            %read all files
            matfile_list = cellfun(k,c(:,1),c(:,2),'UniformOutput',false);

            %function to get variable class
            getClass = @(mfile) {whos(mfile).class}';
            for ifile = 1:num_files
                this_mat = matfile_list{ifile};
                %If there is more than one load only the first one
                ind_var = find(contains(getClass(this_mat),'audioClip'),1);

                %if there are no variable of class audioclip, ignor the
                %file
                if ~isempty(ind_var)
                    name_list = [name_list;{this_mat.Properties.Source,ind_var}];%#ok;
                end

            end

            %Remove placeholder
            if isempty(name_list.filepath{1,1})
                name_list(1,:) = [];
            end

            if ~isempty(name_list)
                val_falg = true;
            end

            function emptyFolderWarning(folder_name)
                warning('No files in this directory:\n%s',folder_name)
            end
        end

        function update_file_list(~,hfile_list,name_list)
            %Read folder and get names of file with audioClip variable in

            %Repack variable
            joincell = @(a,b) {a,b};
            inds = name_list.variableInd;
            if ~iscell(inds)
                inds = num2cell(inds);
            end
            name_list_reshaped = cellfun(joincell,name_list.filepath,inds,'UniformOutput',false);

            hfile_list.Items = name_list.filepath;
            hfile_list.ItemsData = name_list_reshaped;

            hfile_list.Value = {};

        end


        function results = create_table(~)
            %If the items is empty, create a table
            results = cell2table({{''},{[]}},'VariableNames',{'filepath','variableInd'});
            results(1,:) = [];
        end

        function new_list = remove_names_from_list(~,source_list,names_to_remove)
            [~,ia] = setdiff(source_list.filepath,names_to_remove.filepath);

            new_list = source_list(ia,:);
        end

        function tabout = fix_table(app,items)

            tabout = app.create_table();
            filepath = items(:,1) ;
            variableInd = items(:,2);
            variableInd = num2cell(variableInd);
            tabout = [tabout;[filepath,variableInd]];
        end

        function uprate_all_file_lists(app)
            update_file_list(app,app.file_list_listbox,app.name_list_tab)
            update_file_list(app,app.selected_file_list_listbox,app.selected_name_list_tab)
            if ~isempty(app.hHybridMuse)
                app.hHybridMuse.load_and_create_excel_file_list.file_list = app.selected_name_list_tab;
            end
        end

        function update_source_folder(app,dirname)
            if dirname
                app.source_folder_label.Text = dirname;
                [val_flag,name_list] = get_variables_names_in_folder(app,dirname);
                if val_flag
                    app.name_list_tab = name_list;
                    update_file_list(app,app.file_list_listbox,name_list)
                end

                if ~isempty(app.hHybridMuse)
                    app.hHybridMuse.load_and_create_excel_file_list.last_source_folder = dirname;
                end
            end


        end

        function update_save_folder(app,dirname)
            app.save_folder_label.Text = dirname;
            app.save_folder = dirname;
            if ~isempty(app.hHybridMuse)
                app.hHybridMuse.load_and_create_excel_file_list.last_save_folder = dirname;
            end
        end
        
        function update_audio_folder(app, dirname)
            app.audio_folder_label.Text = dirname;
            app.audio_folder = dirname;
            if ~isempty(app.hHybridMuse)
                app.hHybridMuse.load_and_create_excel_file_list.last_audio_folder = dirname;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hHybridMuse)
            source_dirname = pwd;
            save_dirname = pwd;

            if nargin>1
                app.hHybridMuse = hHybridMuse;
                %Get last source folder - cannot be empty
                source_dirname_ = app.hHybridMuse.load_and_create_excel_file_list.last_source_folder;
                if ~isempty(source_dirname_)
                    source_dirname = source_dirname_;
                end

                %Get last save folder - can be empty
                save_dirname = app.hHybridMuse.load_and_create_excel_file_list.last_save_folder;
            end

            update_source_folder(app,source_dirname);
            update_save_folder(app,save_dirname);
            %
            %             app.name_list_tab = create_table(app);
            %             app.selected_name_list_tab = create_table(app);
        end

        % Button pushed function: set_source_folder_button
        function set_source_folder_buttonButtonPushed(app, event)
            dirname = uigetdir;
            update_source_folder(app,dirname);
        end

        % Button pushed function: select_file_button
        function select_file_buttonButtonPushed(app, event)
            %Transfer selected files
            selected_items = app.file_list_listbox.Value;
            if isempty(selected_items)
                return
            end

            selected_items = vertcat(selected_items{:});
            selected_items = fix_table(app,selected_items);

            app.selected_name_list_tab = [app.selected_name_list_tab;selected_items];
            app.name_list_tab = app.remove_names_from_list(app.name_list_tab,app.selected_name_list_tab);

            uprate_all_file_lists(app)
        end

        % Button pushed function: unselect_button
        function unselect_buttonButtonPushed(app, event)
            %Transfer selected files
            selected_items = app.selected_file_list_listbox.Value;
            if isempty(selected_items)
                return
            end

            selected_items = vertcat(selected_items{:});
            selected_items = fix_table(app,selected_items);
            %**********************************
            app.name_list_tab = [app.name_list_tab;selected_items];

            app.selected_name_list_tab = app.remove_names_from_list(app.selected_name_list_tab,app.name_list_tab);
            %**********************************
            uprate_all_file_lists(app)
        end

        % Button pushed function: select_all_files_button
        function select_all_files_buttonButtonPushed(app, event)
            if isempty(app.file_list_listbox.Items)
                return
            end
            app.file_list_listbox.Value = app.file_list_listbox.ItemsData;
        end

        % Button pushed function: run_button
        function run_buttonButtonPushed(app, event)
            if ~isempty(app.hHybridMuse)
                if app.SaveexcelfilesCheckBox.Value
                    app.hHybridMuse.load_and_create_excel_file_list.valid_excel_flag = true;
                else
                    app.hHybridMuse.load_and_create_excel_file_list.valid_excel_flag = false;
                end
                if app.SavedeepphenptypingfilesCheckBox.Value
                    app.hHybridMuse.load_and_create_excel_file_list.valid_deeppehnotying_flag = true;
                else
                    app.hHybridMuse.load_and_create_excel_file_list.valid_deeppehnotying_flag = false;
                end
            end
            delete(app)
        end

        % Button pushed function: set_save_folder_button
        function set_save_folder_buttonButtonPushed(app, event)
            dirname = uigetdir;
            update_save_folder(app,dirname)
        end

        % Button pushed function: use_same_save_folder_button
        function use_same_save_folder_buttonButtonPushed(app, event)
            dirname = '';
            update_save_folder(app,dirname)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.hHybridMuse)
                app.hHybridMuse.load_and_create_excel_file_list.valid_flag = false;
            end
            delete(app)
        end

        % Button pushed function: set_source_folder_button_2
        function set_source_folder_button_2ButtonPushed(app, event)
            dirname = uigetdir;
            update_audio_folder(app,dirname)
        end

        % Value changed function: ResetAudioFolderButton
        function ResetAudioFolderButtonValueChanged(app, event)
            value = app.ResetAudioFolderButton.Value;
            app.update_audio_folder('')
        end

        % Value changed function: SavedeepphenptypingfilesCheckBox
        function SavedeepphenptypingfilesCheckBoxValueChanged(app, event)
            value = app.SavedeepphenptypingfilesCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.file_list_listbox = uilistbox(app.GridLayout);
            app.file_list_listbox.Items = {};
            app.file_list_listbox.Multiselect = 'on';
            app.file_list_listbox.Value = {};
            app.selected_file_list_listbox = uilistbox(app.GridLayout);
            app.selected_file_list_listbox.Items = {};
            app.selected_file_list_listbox.Multiselect = 'on';
            app.selected_file_list_listbox.Value = {};
            app.set_source_folder_button = uibutton(app.GridLayout, 'push');
            app.set_source_folder_button.ButtonPushedFcn = createCallbackFcn(app, @set_source_folder_buttonButtonPushed, true);
            app.set_source_folder_button.Text = 'Set Source Folder';
            app.select_file_button = uibutton(app.GridLayout, 'push');
            app.select_file_button.ButtonPushedFcn = createCallbackFcn(app, @select_file_buttonButtonPushed, true);
            app.select_file_button.Text = 'Select ->';
            app.run_button = uibutton(app.GridLayout, 'push');
            app.run_button.ButtonPushedFcn = createCallbackFcn(app, @run_buttonButtonPushed, true);
            app.run_button.Text = 'Run';
            app.source_folder_label = uilabel(app.GridLayout);
            app.unselect_button = uibutton(app.GridLayout, 'push');
            app.unselect_button.ButtonPushedFcn = createCallbackFcn(app, @unselect_buttonButtonPushed, true);
            app.unselect_button.Text = '<- Unselect';
            app.select_all_files_button = uibutton(app.GridLayout, 'push');
            app.select_all_files_button.ButtonPushedFcn = createCallbackFcn(app, @select_all_files_buttonButtonPushed, true);
            app.select_all_files_button.Text = 'Select all';
            app.set_save_folder_button = uibutton(app.GridLayout, 'push');
            app.set_save_folder_button.ButtonPushedFcn = createCallbackFcn(app, @set_save_folder_buttonButtonPushed, true);
            app.set_save_folder_button.Text = 'Set Save Folder';
            app.save_folder_label = uilabel(app.GridLayout);
            app.use_same_save_folder_button = uibutton(app.GridLayout, 'push');
            app.use_same_save_folder_button.ButtonPushedFcn = createCallbackFcn(app, @use_same_save_folder_buttonButtonPushed, true);
            app.use_same_save_folder_button.Text = 'Save at the same folder';
            app.set_source_folder_button_2 = uibutton(app.GridLayout, 'push');
            app.set_source_folder_button_2.ButtonPushedFcn = createCallbackFcn(app, @set_source_folder_button_2ButtonPushed, true);
            app.set_source_folder_button_2.Text = 'Set Audio Folder';
            app.audio_folder_label = uilabel(app.GridLayout);
            app.ResetAudioFolderButton = uibutton(app.GridLayout, 'state');
            app.ResetAudioFolderButton.ValueChangedFcn = createCallbackFcn(app, @ResetAudioFolderButtonValueChanged, true);
            app.ResetAudioFolderButton.Text = 'Reset Audio Folder';
            app.SaveexcelfilesCheckBox = uicheckbox(app.GridLayout);
            app.SaveexcelfilesCheckBox.Text = 'Save excel files';
            app.SaveexcelfilesCheckBox.Value = true;
            app.SavedeepphenptypingfilesCheckBox = uicheckbox(app.GridLayout);
            app.SavedeepphenptypingfilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavedeepphenptypingfilesCheckBoxValueChanged, true);
            app.SavedeepphenptypingfilesCheckBox.Text = 'Save deepphenptyping files';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.source_folder_label.ContextMenu = app.ContextMenu;
            app.save_folder_label.ContextMenu = app.ContextMenu;
            app.audio_folder_label.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = load_and_save_excel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end