classdef overlaySniffingOnSpect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        ReplotButton          matlab.ui.control.Button
        ScaleEditFieldLabel   matlab.ui.control.Label
        ScaleEditField        matlab.ui.control.NumericEditField
        YShiftEditFieldLabel  matlab.ui.control.Label
        YShiftEditField       matlab.ui.control.NumericEditField
        XShiftEditFieldLabel  matlab.ui.control.Label
        XShiftEditField       matlab.ui.control.NumericEditField
        ExportFigureButton    matlab.ui.control.Button
        RightPanel            matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        obj1  %Handle to object 1 - audio file
        obj2  %Handle to object 2 - overlay
    end
    
    methods (Access = private)


        function updateLimits(app)
            %TODO - define limist for shift and scale
            %             f = app.obj1.f;
            %             ylims = [0 0];
            %             ylims(1) = f(1);
            %             ylims2(2) = f(end);
        end
        
        function app = table2roi(app,ax,source)
            
            %Delete existing rois
            delete(findobj(ax,'Type','images.roi.rectangle'));
            
            %Redraw
            tab = source.roiTable;
            labelColors = source.allROIs;
            timeRange = source.times_;
            ax.XLim = timeRange;
            ax.YLim = [0 125000];
            positions = [tab.TimeStart,tab.FrLow,tab.TimeEnd-tab.TimeStart,tab.FrHigh-tab.FrLow];
            
            labels = tab.Label;
            
            sz = size(positions,1);
            for i = 1:sz
                if timeRange(1)<=positions(i,1)&&timeRange(2)>=(positions(i,1)+positions(i,3))
                    
                    thisLabels = labels(i,1);
                    col =labelColors{ismember(labelColors.Name,thisLabels),'Color'};
                    if isempty(col)
                        col = [0 0 0] ;
                        warning('Could not find lable "%s", and was added',thisLabels)
                        source.addedTypeList = {thisLabels,col,""};
                    end
                    drawrectangle('Parent',ax,"Position",positions(i,:),"Label",labels(i,:),"Color",col,"LineWidth",1) ;
                end
            end
        end
    end
    
    methods (Access = public)
        function updatePlot(app)
            delete(app.UIAxes.Children);
            
            objSpect = app.obj1;
            objSniff = app.obj2;


            yshift = app.YShiftEditField.Value;
            yscale = app.ScaleEditField.Value;
            xshift = app.XShiftEditField.Value;
            
          
            app.UIAxes.XLim = objSpect.times_;
            vec2 = objSniff.vec;
            fs2 = objSniff.fs;
           
            
            t1 = objSpect.timeVec;
            
            s = objSpect.stft;
            f = objSpect.cyclicalF;
            if ~isempty(s)
                surf(app.UIAxes,t1,f,s,'EdgeColor','none')
                view(app.UIAxes,2)
            end
            
            %            climModeSpect = objSpect.climMode;
            climSpect = objSpect.clim;
            table2roi(app,app.UIAxes,objSpect);
            if ~isempty(climSpect)
                app.UIAxes.CLim = climSpect;
            end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            hold(app.UIAxes,'on')
              times2 = objSniff.times_;
               tstart = max(times2(1)*fs2,0);
              tend = min(times2(end)*fs2,numel(vec2));
            vec2 = vec2(floor((1+tstart):(tend)));
            
            times2=([tstart/fs2,tend/fs2]);
            
            if ~isempty(vec2)
                %             vec2 = rescale(vec2,-1,1);
                vec2 = imgaussfilt(vec2,12);
                vec2 = vec2./max(abs(vec2));
                vec2 = reshape(vec2,[],1);
                
                
                diffs = diff(vec2);
                n = 0;
                diffs = [diffs;ones(n,1)*diffs(end)];
                
                diffs = diffs./max(abs(diffs));
                diffs = diffs(1:end-n);
                flattnessLimit = 0.02;
                
                downVec = diffs<-flattnessLimit;
                upVec = diffs>flattnessLimit;
                flatVec = diffs<=flattnessLimit & diffs>=-flattnessLimit;
                times2 = times2-xshift;
                tvec = linspace(times2(1),times2(end),numel(vec2));
                vec2 = rescale(vec2,-1, 1);
                
                markerSize = 2;
                if isempty(s)
                    zVal = 1;
                else
                    zVal = max(max(s)).*1.5;
                end
                plot3(app.UIAxes,tvec,(vec2).*yscale+yshift,ones(1,numel(vec2))*zVal,' .black','MarkerSize',markerSize,"MarkerFaceColor",'black');
                
%                 plot3(app.UIAxes,tvec(downVec),(vec2(downVec)).*yscale+yshift,ones(1,sum(downVec))*zVal,' .blue','MarkerSize',markerSize,"MarkerFaceColor",'blue');
%                 plot3(app.UIAxes,tvec(upVec),(vec2(upVec)).*yscale+yshift,ones(1,sum((upVec)))*zVal,' .r    ','MarkerSize',markerSize,"MarkerFaceColor",'r');
%                 plot3(app.UIAxes,tvec(flatVec),(vec2(flatVec)).*yscale+yshift,ones(1,sum(flatVec))*zVal,' .black','MarkerSize',markerSize,"MarkerFaceColor",'black');
            end
            
            hold(app.UIAxes,'off')


        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj1, obj2)
            if isempty(obj1)||isempty(obj2)
                warning('Both audioClip object must not be empty')
                delete(app)
                return
            end
            
            app.obj1 =  obj1;
            app.obj2 = obj2;
            app.XShiftEditField.Value = -app.obj1.triggerTime+app.obj2.triggerTime;
            %app.updateLimits; %TODO
            app.updatePlot;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: ReplotButton
        function ReplotButtonPushed(app, event)
            app.updatePlot;
        end

        % Value changed function: XShiftEditField
        function XShiftEditFieldValueChanged(app, event)
            %             value = app.XShiftEditField.Value;
            app.updatePlot;
        end

        % Value changed function: YShiftEditField
        function YShiftEditFieldValueChanged(app, event)
            %             value = app.YShiftEditField.Value;
            app.updatePlot;
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(app, event)
            %             value = app.ScaleEditField.Value;
            app.updatePlot;
        end

        % Button pushed function: ExportFigureButton
        function ExportFigureButtonPushed(app, event)
            figure;
            axNew = axes;
            hAx = app.UIAxes;
            name = app.obj1.info.fileName;
            % Copy all objects from UIAxes to new axis
            copyobj(hAx.Children,axNew)
            axNew.XLim = hAx.XLim;
            axNew.YLim = hAx.YLim;
            axNew.CLim = hAx.CLim;
            axNew.Title = hAx.Title;
            axNew.XLabel = hAx.XLabel;
            axNew.YLabel = hAx.YLabel;
            title(axNew,name)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.ReplotButton = uibutton(app.GridLayout2, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotButtonPushed, true);
            app.ReplotButton.Text = 'Replot';
            app.ScaleEditFieldLabel = uilabel(app.GridLayout2);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ScaleEditField.ValueDisplayFormat = '%.0f';
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ScaleEditField.Value = 20000;
            app.YShiftEditFieldLabel = uilabel(app.GridLayout2);
            app.YShiftEditFieldLabel.Text = 'YShift';
            app.YShiftEditField = uieditfield(app.GridLayout2, 'numeric');
            app.YShiftEditField.ValueDisplayFormat = '%.0f';
            app.YShiftEditField.ValueChangedFcn = createCallbackFcn(app, @YShiftEditFieldValueChanged, true);
            app.YShiftEditField.Value = 60000;
            app.XShiftEditFieldLabel = uilabel(app.GridLayout2);
            app.XShiftEditFieldLabel.Text = 'XShift';
            app.XShiftEditField = uieditfield(app.GridLayout2, 'numeric');
            app.XShiftEditField.ValueDisplayFormat = '%.3f';
            app.XShiftEditField.ValueChangedFcn = createCallbackFcn(app, @XShiftEditFieldValueChanged, true);
            app.ExportFigureButton = uibutton(app.GridLayout2, 'push');
            app.ExportFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigureButtonPushed, true);
            app.ExportFigureButton.Text = 'Export Figure';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = overlaySniffingOnSpect(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end