classdef plotCWTApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Controls    matlab.ui.container.Panel
        Plots       matlab.ui.container.Panel
        GridLayout  matlab.ui.container.GridLayout
        AxMini      matlab.ui.control.UIAxes
        AxMain      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        obj1 % Description
        obj2
    end
    
    methods (Access = public)
        
        function updatePlots(app)
            plotCWT(app.obj1,[], app.AxMain)
            plotCWT(app.obj2,[], app.AxMini)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj1, obj2)
            if isempty(obj1)||isempty(obj2)
                warning('Both audioClip object must not be empty')
%                 delete(app)
%                 return
            end
            
            app.obj1 =  obj1;
            app.obj2 = obj2;

            %app.updateLimits; %TODO
            app.updatePlots;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Plots = uipanel(app.UIFigure);
            app.Plots.Title = 'Plots';
            app.GridLayout = uigridlayout(app.Plots);
            app.AxMain = uiaxes(app.GridLayout);
            title(app.AxMain, 'Title')
            xlabel(app.AxMain, 'X')
            ylabel(app.AxMain, 'Y')
            app.AxMini = uiaxes(app.GridLayout);
            title(app.AxMini, 'Title')
            xlabel(app.AxMini, 'X')
            ylabel(app.AxMini, 'Y')
            app.Controls = uipanel(app.UIFigure);
            app.Controls.Title = 'Controls';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotCWTApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end