classdef loadVariabFromWorkspace < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        CancelButton       matlab.ui.control.Button
        fileName           matlab.ui.control.EditField
        DropDownVariables  matlab.ui.control.DropDown
        LoadButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        varName
        varType
        audioBrowserObj
        audioBrowserObjTag
    end
    
    methods (Access = private)
        
        function var_flag = updataVariablList(app)
            var_flag = true;
            vars = evalin('base', 'whos');
            viableClasses = {'double','audioClip'};
            vars = vars(ismember({vars.class},viableClasses));
            
            names = {vars.name}';
            if isempty(names)
                warning('No variables were found')
                var_flag = false;
                return
            end
            classes = {vars.class}';


            name_class =[names,classes];
            s = strcat(names,' (',classes,')');
            
            dataIn = cell(1,size(s,1));
            for i = 1:size(s,1)
                dataIn{1,i} = name_class(i,:)  ;
            end
            
            app.DropDownVariables.Items = s;
            app.DropDownVariables.ItemsData = dataIn;
            
        end
        
        function updateVars(app)
            value = app.DropDownVariables.Value;
            app.fileName.Value = strcat(value{1},' (',value{2},')');
            app.varName = value{1};
            app.varType = value{2};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, audioBrowserObj, tag)
            var_flag = updataVariablList(app);
            if var_flag
            updateVars(app)
            app.audioBrowserObj = audioBrowserObj;
            app.audioBrowserObjTag = tag;
            else
               delete(app) 
            end
        end

        % Value changed function: DropDownVariables
        function DropDownVariablesValueChanged(app, event)
            updateVars(app)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            if isempty(app.varName)||isempty(app.varType)
                return
            else
                obj = app.audioBrowserObj;
                tag = app.audioBrowserObjTag;


                var = evalin('base', app.varName);
                %If the loaded variable is a double
                if isa(var,'double')
                    var = audioClip(var);
                end
                switch tag
                    case 'main'
                        currentObj = obj.mainData;
                        if currentObj~=var %If the loaded object is the same as the one in use, do nothing if they are not thhe same, delete the old one and replace it
                            delete(currentObj);
                            obj.mainData = var;
                            obj.mainVecSource = 'workspace';
                        end
                    case 'mini'
                         currentObj = obj.miniData;
                        if currentObj~=var %If the loaded object is the same as the one in use, do nothing if they are not thhe same, delete the old one and replace it
                            delete(currentObj);
                            obj.miniData = var;
                            obj.miniVecSource = 'workspace';
                        end
                end
                delete(app)
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DropDownVariables = uidropdown(app.GridLayout);
            app.DropDownVariables.ValueChangedFcn = createCallbackFcn(app, @DropDownVariablesValueChanged, true);
            app.fileName = uieditfield(app.GridLayout, 'text');
            app.fileName.Editable = 'off';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loadVariabFromWorkspace(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end