classdef multi_detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        DisplayprogressforeachfileCheckBox  matlab.ui.control.CheckBox
        CloseandRunButton      matlab.ui.control.Button
        AddNames_text          matlab.ui.control.EditField
        AddnametothefilesEditFieldLabel  matlab.ui.control.Label
        SetMultiFileDetectionParametersLabel  matlab.ui.control.Label
        InputTypeButtonGroup   matlab.ui.container.ButtonGroup
        matButton              matlab.ui.control.RadioButton
        wavButton              matlab.ui.control.RadioButton
        SaveFolderLabel        matlab.ui.control.Label
        SetSaveFolderButton    matlab.ui.control.Button
        SourceFolderLabel      matlab.ui.control.Label
        SetSourceFolderButton  matlab.ui.control.Button
    end


    properties (Access = private)
        source_folder
        save_folder
        HybridMuseApp_handle
        added_name = '';
        file_type
        display_flag ;
    end

    methods (Access = private)

        function [Valid, Msg] = CheckFileName(~,S)
            Msg = '';
            if ispc
                BadChar = '<>:"/\|?*';
                BadName = {'CON', 'PRN', 'AUX', 'NUL', 'CLOCK$', ...
                    'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', ...
                    'COM7', 'COM8', 'COM9', ...
                    'LPT1', 'LPT2', 'LPT3', 'LPT4', 'LPT5', 'LPT6', ...
                    'LPT7', 'LPT8', 'LPT9'};
                bad = ismember(BadChar, S);
                if any(bad)
                    Msg = ['Name contains bad characters: ', BadChar(bad)];
                elseif any(S < 32)
                    Msg = ['Name contains non printable characters, ASCII:', sprintf(' %d', S(S < 32))];
                elseif ~isempty(S) && (S(end) == ' ' || S(end) == '.')
                    Msg = 'A trailing space or dot is forbidden';
                else
                    % "AUX.txt" fails also, so extract the file name only:
                    [~, name] = fileparts(S);
                    if any(strcmpi(name, BadName))
                        Msg = ['Name not allowed: ', name];
                    end
                end
            else  % Mac and Linux:
                if any(S == '/')
                    Msg = '/ is forbidden in a file name';
                elseif any(S == 0)
                    Msg = '\0 is forbidden in a file name';
                end
            end
            Valid = isempty(Msg);
        end

        function setup_parameters(app)


            multi_detection_parameters = app.HybridMuseApp_handle.multi_detection_parameters;

            %Make sure the folder exists
            source_folder_temp = multi_detection_parameters.source_folder;
            if ~isfolder(source_folder_temp)
                source_folder_temp = '';
            end
            app.SourceFolderLabel.Text = source_folder_temp;
            app.source_folder = source_folder_temp;

            %Make sure the folder exists
            save_folder_temp = multi_detection_parameters.save_folder;
            if ~isfolder(save_folder_temp)
                save_folder_temp = '';
            end
            app.SaveFolderLabel.Text = save_folder_temp;
            app.save_folder = multi_detection_parameters.save_folder;
            
            %Make sure file type is valid
            file_type_temp = multi_detection_parameters.file_type;
            switch file_type_temp
                case {'wav','mat'}
                    
                otherwise
                file_type_temp = '';
            end
            app.InputTypeButtonGroup.SelectedObject.Text = file_type_temp;
            app.file_type = file_type_temp;
            
            added_name_temp = multi_detection_parameters.added_appendix;
            [Valid, ~] = CheckFileName(app,added_name_temp);
            if ~Valid
                added_name_temp = '';
            end
            app.AddNames_text.Value = added_name_temp;
            app.added_name = added_name_temp;

        end


        function val = check_if_all_is_valid(app)
            multi_detection_parameters = app.HybridMuseApp_handle.multi_detection_parameters;
            if isfolder(multi_detection_parameters.save_folder) && isfolder(multi_detection_parameters.source_folder)
                val = true;
            else
                val = false;
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hHybridMuseApp)
            app.HybridMuseApp_handle = hHybridMuseApp;
            app.setup_parameters;
        end

        % Button pushed function: SetSourceFolderButton
        function SetSourceFolderButtonPushed(app, event)
            folder_name = uigetdir;
            drawnow;
            figure(app.UIFigure)

            if folder_name
                app.SourceFolderLabel.Text = folder_name;

                %Update main app
                multi_detection_parameters = app.HybridMuseApp_handle.multi_detection_parameters;
                multi_detection_parameters.source_folder = folder_name;
                app.HybridMuseApp_handle.multi_detection_parameters = multi_detection_parameters;
            end
        end

        % Button pushed function: SetSaveFolderButton
        function SetSaveFolderButtonPushed(app, event)
            folder_name = uigetdir;
            drawnow;
            figure(app.UIFigure)

            if folder_name
                app.SaveFolderLabel.Text = folder_name;

                %Update main app
                multi_detection_parameters = app.HybridMuseApp_handle.multi_detection_parameters;
                multi_detection_parameters.save_folder = folder_name;
                app.HybridMuseApp_handle.multi_detection_parameters = multi_detection_parameters;
            end
        end

        % Value changed function: AddNames_text
        function AddNames_textValueChanged(app, event)
            S = app.AddNames_text.Value;
            S = char(S);
            [Valid, Msg] = CheckFileName(app,S);
            if Valid
                app.added_name = S;
                %Update main app
                multi_detection_parameters = app.HybridMuseApp_handle.multi_detection_parameters;
                multi_detection_parameters.added_appendix = S;
                app.HybridMuseApp_handle.multi_detection_parameters = multi_detection_parameters;
            else
                %Revert back
                warning(Msg)
                app.AddNames_text.Value = app.added_name;
            end


        end

        % Selection changed function: InputTypeButtonGroup
        function InputTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputTypeButtonGroup.SelectedObject.Text;

            multi_detection_parameters = app.HybridMuseApp_handle.multi_detection_parameters;

            multi_detection_parameters.file_type = selectedButton;
            app.file_type = selectedButton;
            app.HybridMuseApp_handle.multi_detection_parameters = multi_detection_parameters;
        end

        % Value changed function: DisplayprogressforeachfileCheckBox
        function DisplayprogressforeachfileCheckBoxValueChanged(app, event)
            value = app.DisplayprogressforeachfileCheckBox.Value;

            multi_detection_parameters = app.HybridMuseApp_handle.multi_detection_parameters;
            app.HybridMuseApp_handle.multi_detection_parameters = multi_detection_parameters;

            multi_detection_parameters.display_flag = value;
            app.display_flag = value;
        end

        % Button pushed function: CloseandRunButton
        function CloseandRunButtonPushed(app, event)
            if check_if_all_is_valid(app)
                multi_detection_parameters = app.HybridMuseApp_handle.multi_detection_parameters;
                multi_detection_parameters.all_valid_flag = true;
                app.HybridMuseApp_handle.multi_detection_parameters = multi_detection_parameters;
            end

            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %If the app was closed in a different way than "close and run
            %button", set state to false to prevent process
            try
                multi_detection_parameters = app.HybridMuseApp_handle.multi_detection_parameters;
                multi_detection_parameters.all_valid_flag = false;
                app.HybridMuseApp_handle.multi_detection_parameters = multi_detection_parameters;
            end
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SetSourceFolderButton = uibutton(app.GridLayout, 'push');
            app.SetSourceFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SetSourceFolderButtonPushed, true);
            app.SetSourceFolderButton.Text = 'Set Source Folder';
            app.SourceFolderLabel = uilabel(app.GridLayout);
            app.SourceFolderLabel.Text = 'Source Folder';
            app.SetSaveFolderButton = uibutton(app.GridLayout, 'push');
            app.SetSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SetSaveFolderButtonPushed, true);
            app.SetSaveFolderButton.Text = 'Set Save Folder';
            app.SaveFolderLabel = uilabel(app.GridLayout);
            app.SaveFolderLabel.Text = 'Save Folder';
            app.InputTypeButtonGroup = uibuttongroup(app.GridLayout);
            app.InputTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputTypeButtonGroupSelectionChanged, true);
            app.InputTypeButtonGroup.Title = 'Input Type';
            app.wavButton = uiradiobutton(app.InputTypeButtonGroup);
            app.wavButton.Text = 'wav';
            app.wavButton.Value = true;
            app.matButton = uiradiobutton(app.InputTypeButtonGroup);
            app.matButton.Text = 'mat';
            app.SetMultiFileDetectionParametersLabel = uilabel(app.GridLayout);
            app.SetMultiFileDetectionParametersLabel.Text = 'Set Multi File Detection Parameters';
            app.AddnametothefilesEditFieldLabel = uilabel(app.GridLayout);
            app.AddnametothefilesEditFieldLabel.Text = 'Add name to the files';
            app.AddNames_text = uieditfield(app.GridLayout, 'text');
            app.AddNames_text.ValueChangedFcn = createCallbackFcn(app, @AddNames_textValueChanged, true);
            app.CloseandRunButton = uibutton(app.GridLayout, 'push');
            app.CloseandRunButton.ButtonPushedFcn = createCallbackFcn(app, @CloseandRunButtonPushed, true);
            app.CloseandRunButton.Text = 'Close and Run';
            app.DisplayprogressforeachfileCheckBox = uicheckbox(app.GridLayout);
            app.DisplayprogressforeachfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayprogressforeachfileCheckBoxValueChanged, true);
            app.DisplayprogressforeachfileCheckBox.Text = 'Display progress for each file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multi_detection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end