classdef splitterEditorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        ControlsPanel                 matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        GrindLunch                    matlab.ui.container.GridLayout
        RunButton                     matlab.ui.control.Button
        DestinationText               matlab.ui.control.TextArea
        DestinationChangeButton       matlab.ui.control.Button
        DestinationLabel              matlab.ui.control.Label
        ClipsizeRangesLabel           matlab.ui.control.Label
        EditFieldSizeArray            matlab.ui.control.EditField
        LoadROIsButton                matlab.ui.control.Button
        SaveImageCheckBox             matlab.ui.control.CheckBox
        SaveClipsCheckBox             matlab.ui.control.CheckBox
        SaveaudioClipobjectsCheckBox  matlab.ui.control.CheckBox
        UpdateandCloseButton          matlab.ui.control.Button
        GridLayout3                   matlab.ui.container.GridLayout
        WindowmsbinsLabel             matlab.ui.control.Label
        WindowSec                     matlab.ui.control.NumericEditField
        OverlapSec                    matlab.ui.control.NumericEditField
        FFTwinSec                     matlab.ui.control.NumericEditField
        SamplingRateBinPerSec         matlab.ui.control.NumericEditField
        SamplingRatessecLabel         matlab.ui.control.Label
        WindowBin                     matlab.ui.control.NumericEditField
        OverlapBin                    matlab.ui.control.NumericEditField
        FFTwinBin                     matlab.ui.control.NumericEditField
        OverlapManualButton           matlab.ui.control.StateButton
        FFTManualButton               matlab.ui.control.StateButton
        GridVisualizationsControls    matlab.ui.container.GridLayout
        CLimEndSlider                 matlab.ui.control.Slider
        CLimStartEditField            matlab.ui.control.NumericEditField
        CLimEndEditField              matlab.ui.control.NumericEditField
        CLimLabel                     matlab.ui.control.Label
        CLimStartSlider               matlab.ui.control.Slider
        lowerlimLabel                 matlab.ui.control.Label
        upperlimLabel                 matlab.ui.control.Label
        ManualCLimCheckBox            matlab.ui.control.CheckBox
        GridLayout7                   matlab.ui.container.GridLayout
        TriggerTimeLabel              matlab.ui.control.Label
        TriggerTimeEditField          matlab.ui.control.NumericEditField
        TimeStartEditField            matlab.ui.control.NumericEditField
        TimeEndEditField              matlab.ui.control.NumericEditField
        TimeLabel                     matlab.ui.control.Label
        ResetCLinButton               matlab.ui.control.Button
        ReplotButton                  matlab.ui.control.Button
        GridLayout8                   matlab.ui.container.GridLayout
        MaxFrequencyHzLabel           matlab.ui.control.Label
        MaxFrequencyHzEditField       matlab.ui.control.NumericEditField
        MinFrequencyHzLabel           matlab.ui.control.Label
        MinFrequencyHzEditField       matlab.ui.control.NumericEditField
        GridLayout9                   matlab.ui.container.GridLayout
        ResampleButton                matlab.ui.control.Button
        ResampleEditField             matlab.ui.control.NumericEditField
        NewSamplingRateLabel          matlab.ui.control.Label
        VisualizationPanel            matlab.ui.container.Panel
        GridAxes                      matlab.ui.container.GridLayout
        axSpect                       matlab.ui.control.UIAxes
        axP                           matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        GridLDestination              matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        GridSize                      matlab.ui.container.GridLayout
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        audioClipObj = audioClip;
        destinationFolder = '' % Destination folder name
        fileName = '';
        times=[0 0]; % Time range
        clim = [0 0]; % CLim range of spectrogram
        ylim = [0 125000];
        clipSize = 5; % Clip sizes or time tamps of where to cut
        
        originalFs
        originalVec
        originalClims;
        originalYlims;


    end
    
    methods (Access = private)
        
        function updateSliders(app)
            
            tempClims = app.clim;
            sliderRange = diff(tempClims)*2;
            
            app.CLimStartSlider.Limits = [tempClims(1)-sliderRange,tempClims(2)];
            app.CLimStartSlider.Value = tempClims(1);
            
            app.CLimEndSlider.Limits = [tempClims(1),tempClims(2)+sliderRange];
            app.CLimEndSlider.Value = tempClims(2);
            
            app.lowerlimLabel.Text = num2str(tempClims(1)-sliderRange);
            app.upperlimLabel.Text = num2str(tempClims(2)+sliderRange);
        end
        
        function updatePlot(app)
            %Check validiti of range
            app.updateTimes;
            
            %Plot
            drawSpectrogram(app.axSpect, app.axP, app.audioClipObj);
            
            %If clims and ylims were empty, now they will not be empty
            app.clim = app.audioClipObj.clim;
            app.updateClims;
            
            app.ylim = app.audioClipObj.ylims;
            app.updateYlims;
        end


        function updateSpectralParams(app)
            %Get spectral parameters from object
            
            obj = app.audioClipObj;
            
            fs = obj.fs;
            %             bins = floor(diff(obj.times)*fs);
            app.SamplingRateBinPerSec.Value = fs;
            
            %I removed window limits
            %             app.WindowSec.Limits = ([0,bins])./fs.*1000;
            %             app.WindowBin.Limits = [0,bins];
            app.WindowSec.Limits = [0,inf];
            app.WindowBin.Limits = [0,inf];
            
            try
                app.WindowSec.Value = obj.window/fs*1000;
                app.WindowBin.Value = obj.window;
            catch
                %The limits are not inclusive so I multiply by 0.9
                %                 app.WindowSec.Value = 0.9*app.WindowSec.Limits(2);
                %                 app.WindowBin.Value = floor(0.9*app.WindowBin.Limits(2));
                app.WindowBin.Value = 512;
                app.WindowSec.Value = 512/fs*1000;
            end
            
            
            app.OverlapSec.Limits = [0,app.WindowSec.Value];
            app.OverlapBin.Limits = [0,app.WindowBin.Value];
            
            if app.OverlapManualButton.Value
                
                try
                    app.OverlapSec.Value = obj.overlap/fs*1000;
                    app.OverlapBin.Value = obj.overlap;
                catch
                    app.OverlapSec.Limits = [0,app.WindowSec.Value];
                    app.OverlapBin.Limits = [0,app.WindowBin.Value];
                    
                    app.OverlapBin.Value = round(app.WindowBin.Value/2);
                    app.OverlapSec.Value = app.OverlapBin.Value/fs*1000;
                end
            else %Auto mode - default
                app.OverlapBin.Value = round(app.WindowBin.Value/2);
                app.OverlapSec.Value = app.OverlapBin.Value/fs*1000;
            end
            
            if app.FFTManualButton.Value
                app.FFTwinSec.Limits = ([1,inf])./fs.*1000;
                app.FFTwinBin.Limits = [1,inf];
                try
                    app.FFTwinSec.Value = obj.fft/fs*1000;
                    app.FFTwinBin.Value = obj.fft;
                catch
                    p = ceil(log(app.WindowBin.Value));
                    app.FFTwinBin.Value = max(256,2^p);
                    app.FFTwinSec.Value = app.FFTwinBin.Value/fs*1000;
                end
            else %Auto mode - default
                p = ceil(log(app.WindowBin.Value));
                app.FFTwinBin.Value = max(256,2^p);
                app.FFTwinSec.Value = app.FFTwinBin.Value/fs*1000;
            end
            
        end
        
        function updateSpectValues(app)
            %Set spectral parameters in object
            
            obj = app.audioClipObj;
            fs = app.SamplingRateBinPerSec.Value;
            winBins = app.WindowBin.Value;
            overlapBins = app.OverlapBin.Value;
            fftWinBins = app.FFTwinBin.Value;
            
            %Set values in audioClip object
            obj.window = winBins;
            obj.fs = fs;
            
            %If manual moode is on
            if app.OverlapManualButton.Value
                obj.overlap = overlapBins;
            end
            
            if app.FFTManualButton.Value
                obj.fft = fftWinBins;
            end
            
            %Refresh values in app
            app.updateSpectralParams;
        end
        
        function downsampleVec(app,finalFs)
            
            fs = app.originalFs;
            vec = app.originalVec;
            
            downRatio = fs/finalFs;
            if downRatio <1
                warning('Unable to upsample over original')
                return
            end
            %Round to closest integer
            downRatio = floor(downRatio);
            downVec = downsample(vec,downRatio);
            app.audioClipObj.vec = downVec;
            app.audioClipObj.fs = finalFs;
            
            %Reset spectrel parameters
            app.audioClipObj.window = [];
            app.audioClipObj.fft = [];
            app.audioClipObj.overlap = [];
            
            app.updatePlot;
            app.updateSpectralParams;
            app.updateSpectValues;
        end
        
        function updateClipSize(app,value)
            %Test input
            x=["[","]","0","1","2","3","4","5","6","7","8","9",".",";",",",newline];  % String array
            
            allCharsGood = @(c, goodList)all(ismember(string(cellstr(c')'), goodList));
            allMatch = allCharsGood(value,x);
            if ~allMatch
                warning('Invalid input charachers')
                try
                    app.EditFieldSizeArray.Value = event.PreviousValue;
                catch
                    app.EditFieldSizeArray.Value = [];
                end
                return
            end
            
            
            %value can be scalar or array
            try
                mat = eval(value);
            catch
                try
                    mat = eval(['[',value,']']);
                catch
                    warning('Invalid input')
                    app.EditFieldSizeArray.Value = event.PreviousValue;
                    return
                end
            end
            
            if isnumeric(mat)
                app.clipSize = mat;
            end
        end
        
        
        function updateYlims(app)
            
            appylim = app.ylim;
            maxFs = (app.audioClipObj.fs)/2;
            %If all is 0 or empty, reset to max. this happens after reset
            
            if isempty(appylim)||app.MaxFrequencyHzEditField.Value==0
                appylim = [0, maxFs];
            end
            
            
            %If the input is valid, set it!
            if appylim(2)<=maxFs&&appylim(1)>=0&&~isempty(appylim)
                app.audioClipObj.ylims = appylim;
            else %If it is not valid, reset to original to max range
                appylim = [0,maxFs];
                currYlims = app.audioClipObj.ylims;
                %If empty  or invalid, set to max
                if isempty(currYlims)||currYlims(1)<0||currYlims(2)>maxFs
                    app.audioClipObj.ylims = [0,maxFs];
                else %If not empty and is valid, reset to current
                    appylim = app.audioClipObj.ylims;
                end
            end
            
            %Reset limis
            app.MaxFrequencyHzEditField.Limits = [appylim(1),maxFs];
            app.MinFrequencyHzEditField.Limits = [0,appylim(2)];
            
            %Reset values
            app.ylim = appylim;
            app.MaxFrequencyHzEditField.Value = appylim(2);
            app.MinFrequencyHzEditField.Value = appylim(1);
        end
        
        function updateClims(app)
            
            %Get app clim values
            tempClims = app.clim;


            %If the clims are set to manual and not empty and valid, update
            if (app.ManualCLimCheckBox.Value)&&(~isempty(tempClims))...
                    &&(diff(tempClims)>0)
                tempClims = app.clim; %If app clims are valid and is on manual
                % them get the values
            else
                %If ths clims are set to auto, get clims from the figure
                tempClims = app.axSpect.CLim;
            end
            
            app.clim  = tempClims; %Updata app clims and limits
            app.CLimStartEditField.Value = tempClims(1);
            app.CLimEndEditField.Value   = tempClims(2);
            %Update audioClip object clims
            app.audioClipObj.clim = tempClims;
            
            app.updateSliders;
            
            
        end
        
        function updateTimes(app)
            tempTimes = app.times;
            if ~(diff(tempTimes)<0||diff(tempTimes)>50 ||tempTimes(1)<0)
                app.audioClipObj.times_ = tempTimes;
            else
                tempTimes = app.audioClipObj.times;
                app.times = tempTimes;
                app.TimeStartEditField.Value = tempTimes(1);
                app.TimeEndEditField.Value = tempTimes(2);
            end
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataIn)
            %             if nargin ==1
            %                 dataIn = audioClip;
            %             end
            %Must be initiated with a non empty audioClip object
            if isempty(dataIn)||~isa(dataIn,'audioClip')
                delete(app)
                warning('Input must be a non empty audioClip object')
                return
            end
            app.audioClipObj = dataIn;
            app.originalFs = dataIn.fs;
            app.originalVec = dataIn.vec;
            
            [~,tempFileName] = fileparts(dataIn.info.fileName);
            
            app.fileName = tempFileName;
            app.destinationFolder = fullfile(pwd,tempFileName);
            app.DestinationText.Value = app.destinationFolder;
            app.DestinationText.Editable = 'off';
            
            app.times = dataIn.times;
            app.TimeStartEditField.Value = app.times(1);
            app.TimeEndEditField.Value   = app.times(2);
            
            drawSpectrogram(app.axSpect, app.axP, app.audioClipObj);
            
            %CLim
            manualclimmode = app.audioClipObj.climMode;
            switch manualclimmode
                case 'manual'
                    app.ManualCLimCheckBox.Value = true;
                case 'auto'
                    app.ManualCLimCheckBox.Value = false;
                    %Disable Clim controls
                    app.CLimStartEditField.Enable = 'off';
                    app.CLimEndEditField.Enable = 'off';
                    app.CLimStartSlider.Enable = 'off';
                    app.CLimEndSlider.Enable = 'off';
            end
            
            app.originalClims = app.audioClipObj.clim;
            tempClims = app.audioClipObj.clim;
            
            if isempty(tempClims)
                tempClims = app.axSpect.CLim;
            end
            
            app.clim =  tempClims;
            app.CLimStartEditField.Value = tempClims(1);
            app.CLimEndEditField.Value = tempClims(2);
            app.originalClims = app.audioClipObj.clim;
            
            %ylim
            tempYlims = app.audioClipObj.ylims;
            if isempty(tempYlims)
                app.ylim = [];
            else
                
                app.ylim = tempYlims;
                app.MaxFrequencyHzEditField.Limits = [tempYlims(1),app.audioClipObj.fs/2];
                app.MinFrequencyHzEditField.Limits = [0 tempYlims(2)];
                
                app.MinFrequencyHzEditField.Value = tempYlims(1);
                app.MaxFrequencyHzEditField.Value = tempYlims(2);
                
            end
            app.updateYlims;
            
            %sliders
            app.updateSliders;
            
            %spectrogram parameters
            app.updateSpectralParams;
            
            %Set resampling limits
            app.ResampleEditField.Limits = [0,app.SamplingRateBinPerSec.Value];
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {825, 825};
                app.GridLayout.ColumnWidth = {'1x'};
                app.VisualizationPanel.Layout.Row = 2;
                app.VisualizationPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {524, '1x'};
                app.VisualizationPanel.Layout.Row = 1;
                app.VisualizationPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: DestinationChangeButton
        function DestinationChangeButtonPushed(app, event)
            path = uigetdir;
            if path
                app.destinationFolder = path;
                app.DestinationText.Value = path;
                
            end
        end

        % Value changed function: TimeStartEditField
        function TimeStartEditFieldValueChanged(app, event)
            value = app.TimeStartEditField.Value;
            tempTimes = app.times;
            app.times = [value,tempTimes(2)];
            
        end

        % Value changed function: TimeEndEditField
        function TimeEndEditFieldValueChanged(app, event)
            value = app.TimeEndEditField.Value;
            tempTimes = app.times;
            app.times = [tempTimes(1),value];
        end

        % Button pushed function: ReplotButton
        function ReplotButtonPushed(app, event)
            app.updatePlot;
        end

        % Value changed function: CLimStartEditField
        function CLimStartEditFieldValueChanged(app, event)
            value = app.CLimStartEditField.Value;
            tempClims = app.clim;
            app.clim = [value,tempClims(2)];
            app.updateClims;
        end

        % Value changed function: CLimEndEditField
        function CLimEndEditFieldValueChanged(app, event)
            value = app.CLimEndEditField.Value;
            tempClims = app.clim;
            app.clim = [tempClims(1),value];
            app.updateClims;
        end

        % Value changed function: CLimStartSlider
        function CLimStartSliderValueChanged(app, event)
            value = app.CLimStartSlider.Value;
            app.CLimStartEditField.Value = value;
            tempClim = app.clim;
            if isempty(tempClim)
                app.clim = [value,value + value*0.5];
            else
                app.clim = [value,tempClim(2)];
            end
            app.updateClims;
            app.updatePlot;
        end

        % Value changed function: CLimEndSlider
        function CLimEndSliderValueChanged(app, event)
            value = app.CLimEndSlider.Value;
            app.CLimEndEditField.Value = value;
            tempClim = app.clim;
            if isempty(tempClim)
                app.clim = [value-value*0.5,value];
            else
                app.clim = [tempClim(1),value];
            end
            app.updateClims;
            app.updatePlot;
        end

        % Button pushed function: ResetCLinButton
        function ResetCLinButtonPushed(app, event)
%             %Rest time
%             tempTimes = app.audioClipObj.times;
%             app.TimeStartEditField.Value = tempTimes(1);
%             app.TimeEndEditField.Value = tempTimes(2);
%             app.times = tempTimes;
            
            %Rest CLim
            tempClims = app.originalClims;
            if isempty(tempClims)||~app.ManualCLimCheckBox.Value
                app.clim = [];
            end
            app.updateClims;
            
            %Reset original vector
            app.audioClipObj.vec = app.originalVec;
            
            %Reset sampling rate
            app.audioClipObj.fs = app.originalFs;
            app.SamplingRateBinPerSec.Value = app.originalFs;
            
            %Reset ylims
            app.audioClipObj.ylims = app.originalYlims;
            app.ylim = app.originalYlims;
            app.updateYlims;
            
            
            app.updatePlot;
        end

        % Value changed function: EditFieldSizeArray
        function EditFieldSizeArrayValueChanged(app, event)
            value = app.EditFieldSizeArray.Value;
            app.updateClipSize(value);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            inputs.Name = app.fileName;
            inputs.Destination = app.DestinationText.Value;
            inputs.SaveImgFlag = app.SaveImageCheckBox.Value;
            inputs.SaveClipsFlag = app.SaveClipsCheckBox.Value;
            inputs.SaveAudioClipObjFlag = app.SaveaudioClipobjectsCheckBox.Value;
            inputs.Size = app.clipSize;
            inputs.Format = 'img';
            
            inputs.Data = app.audioClipObj;
            inputs.fs = app.audioClipObj.fs;
            inputs.YLim = app.audioClipObj.ylims;
            inputs.CLim = app.audioClipObj.clim;
            inputs.CLimManualeFlag = app.audioClipObj.climMode;
            
            
            splitFile(inputs);
        end

        % Value changed function: WindowSec
        function WindowSecValueChanged(app, event)
            value = app.WindowSec.Value;
            fs = app.SamplingRateBinPerSec.Value;
            bins = value*fs/1000; %Convert to bins
            app.WindowBin.Value = round(bins);
            app.updateSpectValues;
        end

        % Value changed function: SamplingRateBinPerSec
        function SamplingRateBinPerSecValueChanged(app, event)
            value = app.SamplingRateBinPerSec.Value;
            app.audioClipObj.fs = value;
            app.originalFs = value;
            app.updateSpectralParams;
        end

        % Button pushed function: ResampleButton
        function ResampleButtonPushed(app, event)
            finalFs = app.ResampleEditField.Value;
            downsampleVec(app,finalFs);
            
        end

        % Value changed function: SaveaudioClipobjectsCheckBox
        function SaveaudioClipobjectsCheckBoxValueChanged(app, event)
            value = app.SaveaudioClipobjectsCheckBox.Value;
            if value
                app.SaveClipsCheckBox.Value = true;
            end
        end

        % Button pushed function: LoadROIsButton
        function LoadROIsButtonPushed(app, event)
            tab = app.audioClipObj.roiTable;
            if isempty(tab)
                return
            end
            startTimes = tab.TimeStart;
            endTimes = tab.TimeEnd;
            timesROI = [startTimes,endTimes];
            t = '[';
            for i = 1:size(timesROI,1)-1
                t = [t,sprintf('%f,%f;',times(i,1),times(i,2))];  %#ok
            end
            t = [t,sprintf('%f,%f]',timesROI(i+1,1),timesROI(i+1,2))];
            app.EditFieldSizeArray.Value = t;
            updateClipSize(app,t);
        end

        % Button pushed function: UpdateandCloseButton
        function UpdateandCloseButtonPushed(app, event)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: MaxFrequencyHzEditField
        function MaxFrequencyHzEditFieldValueChanged(app, event)
            value = app.MaxFrequencyHzEditField.Value;
            
            minFreqFieldVal = app.MinFrequencyHzEditField.Value;
            if value<minFreqFieldVal
                app.MinFrequencyHzEditField.Value = value;
            end
            app.MinFrequencyHzEditField.Limits = [0,value];
            tempylims = app.ylim;
            app.ylim = [tempylims(1),value];
            
            app.updateYlims;
        end

        % Value changed function: MinFrequencyHzEditField
        function MinFrequencyHzEditFieldValueChanged(app, event)
            value = app.MinFrequencyHzEditField.Value;
            maxfs = (app.audioClipObj.fs)/2; %niquist frequency
            maxFreqFieldVal = app.MaxFrequencyHzEditField.Value;
            if value>maxFreqFieldVal
                app.MaxFrequencyHzEditField.Value = value;
            end
            app.MaxFrequencyHzEditField.Limits = [value,maxfs];
            tempylims = app.ylim;
            app.ylim = [value,tempylims(2)];
            
            app.updateYlims;
        end

        % Value changed function: ManualCLimCheckBox
        function ManualCLimCheckBoxValueChanged(app, event)
            value = app.ManualCLimCheckBox.Value;
            %remove clim when not on manual
            if ~value
                app.axSpect.CLimMode = 'auto';
                app.audioClipObj.climMode = 'auto';
                app.clim = [];
                app.audioClipObj.clim = [];
                
                %Disable sliders
                app.CLimStartEditField.Enable = 'off';
                app.CLimEndEditField.Enable = 'off';
                app.CLimStartSlider.Enable = 'off';
                app.CLimEndSlider.Enable = 'off';
            else
                app.axSpect.CLimMode = 'manual';
                app.audioClipObj.climMode = 'manual';
                app.CLimStartEditField.Enable = 'on';
                app.CLimEndEditField.Enable = 'on';
                app.CLimStartSlider.Enable = 'on';
                app.CLimEndSlider.Enable = 'on';
            end
        end

        % Value changed function: FFTwinSec
        function FFTwinSecValueChanged(app, event)
            value = app.FFTwinSec.Value;
            fs = app.SamplingRateBinPerSec.Value;
            bins = value*fs/1000; %Convert to bins
            app.FFTwinBin.Value = floor(bins);
            app.updateSpectValues;
        end

        % Value changed function: OverlapSec
        function OverlapSecValueChanged(app, event)
            value = app.OverlapSec.Value;
            fs = app.SamplingRateBinPerSec.Value;
            bins = value*fs/1000; %Convert to bins
            app.OverlapBin.Value = floor(bins);
            app.updateSpectValues;
        end

        % Value changed function: WindowBin
        function WindowBinValueChanged(app, event)
            value = app.WindowBin.Value;
            fs = app.SamplingRateBinPerSec.Value;
            millisecs = value/fs*1000; %Convert to bins
            app.WindowSec.Value = round(millisecs);
            app.updateSpectValues;
        end

        % Value changed function: OverlapBin
        function OverlapBinValueChanged(app, event)
            value = app.OverlapBin.Value;
            fs = app.SamplingRateBinPerSec.Value;
            millisecs = value/fs*1000; %Convert to bins
            app.OverlapSec.Value = round(millisecs);
            app.updateSpectValues;
        end

        % Value changed function: FFTwinBin
        function FFTwinBinValueChanged(app, event)
            value = app.FFTwinBin.Value;
            fs = app.SamplingRateBinPerSec.Value;
            millisecs = value/fs*1000; %Convert to bins
            app.FFTwinSec.Value = round(millisecs);
            app.updateSpectValues;
        end

        % Value changed function: OverlapManualButton
        function OverlapManualButtonValueChanged(app, event)
            value = app.OverlapManualButton.Value;
            if value
                app.OverlapBin.Enable = 'on';
                app.OverlapSec.Enable = 'on';
            else
                app.OverlapBin.Enable = 'off';
                app.OverlapSec.Enable = 'off';
                %Default - 50%
                app.audioClipObj.overlap = round(app.WindowBin.Value/2);
            end
            app.updateSpectralParams;
            
        end

        % Value changed function: FFTManualButton
        function FFTManualButtonValueChanged(app, event)
            value = app.FFTManualButton.Value;
            if value
                app.FFTwinBin.Enable = 'on';
                app.FFTwinSec.Enable = 'on';
            else
                app.FFTwinBin.Enable = 'off';
                app.FFTwinSec.Enable = 'off';
                %Defult mode
                p = ceil(log(app.WindowBin.Value));
                app.audioClipObj.fft = max(256,2*p);
            end
            app.updateSpectralParams;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ControlsPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.ControlsPanel);
            app.GrindLunch = uigridlayout(app.GridLayout2);
            app.RunButton = uibutton(app.GrindLunch, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DestinationText = uitextarea(app.GrindLunch);
            app.DestinationChangeButton = uibutton(app.GrindLunch, 'push');
            app.DestinationChangeButton.ButtonPushedFcn = createCallbackFcn(app, @DestinationChangeButtonPushed, true);
            app.DestinationChangeButton.Text = 'Change Save Folder';
            app.DestinationLabel = uilabel(app.GrindLunch);
            app.DestinationLabel.Text = 'Destination';
            app.ClipsizeRangesLabel = uilabel(app.GrindLunch);
            app.ClipsizeRangesLabel.Text = {'Clip size /'; 'Ranges'};
            app.EditFieldSizeArray = uieditfield(app.GrindLunch, 'text');
            app.EditFieldSizeArray.ValueChangedFcn = createCallbackFcn(app, @EditFieldSizeArrayValueChanged, true);
            app.LoadROIsButton = uibutton(app.GrindLunch, 'push');
            app.LoadROIsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadROIsButtonPushed, true);
            app.LoadROIsButton.Text = 'Load ROIs';
            app.SaveImageCheckBox = uicheckbox(app.GrindLunch);
            app.SaveImageCheckBox.Text = 'Save Image';
            app.SaveImageCheckBox.Value = true;
            app.SaveClipsCheckBox = uicheckbox(app.GrindLunch);
            app.SaveClipsCheckBox.Text = 'Save Clips';
            app.SaveaudioClipobjectsCheckBox = uicheckbox(app.GrindLunch);
            app.SaveaudioClipobjectsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveaudioClipobjectsCheckBoxValueChanged, true);
            app.SaveaudioClipobjectsCheckBox.Text = {'Save'; 'audioClip'; 'objects'};
            app.UpdateandCloseButton = uibutton(app.GrindLunch, 'push');
            app.UpdateandCloseButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateandCloseButtonPushed, true);
            app.UpdateandCloseButton.Text = 'Update and Close';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.WindowmsbinsLabel = uilabel(app.GridLayout3);
            app.WindowmsbinsLabel.Text = 'Window (ms-bins)';
            app.WindowSec = uieditfield(app.GridLayout3, 'numeric');
            app.WindowSec.LowerLimitInclusive = 'off';
            app.WindowSec.UpperLimitInclusive = 'off';
            app.WindowSec.Limits = [0 Inf];
            app.WindowSec.ValueDisplayFormat = '%.1f';
            app.WindowSec.ValueChangedFcn = createCallbackFcn(app, @WindowSecValueChanged, true);
            app.WindowSec.Value = 1;
            app.OverlapSec = uieditfield(app.GridLayout3, 'numeric');
            app.OverlapSec.LowerLimitInclusive = 'off';
            app.OverlapSec.UpperLimitInclusive = 'off';
            app.OverlapSec.Limits = [0 Inf];
            app.OverlapSec.ValueDisplayFormat = '%.1f';
            app.OverlapSec.ValueChangedFcn = createCallbackFcn(app, @OverlapSecValueChanged, true);
            app.OverlapSec.Enable = 'off';
            app.OverlapSec.Value = 1;
            app.FFTwinSec = uieditfield(app.GridLayout3, 'numeric');
            app.FFTwinSec.LowerLimitInclusive = 'off';
            app.FFTwinSec.UpperLimitInclusive = 'off';
            app.FFTwinSec.Limits = [0 Inf];
            app.FFTwinSec.ValueDisplayFormat = '%.1f';
            app.FFTwinSec.ValueChangedFcn = createCallbackFcn(app, @FFTwinSecValueChanged, true);
            app.FFTwinSec.Enable = 'off';
            app.FFTwinSec.Value = 1;
            app.SamplingRateBinPerSec = uieditfield(app.GridLayout3, 'numeric');
            app.SamplingRateBinPerSec.Limits = [0 Inf];
            app.SamplingRateBinPerSec.ValueDisplayFormat = '%.0f';
            app.SamplingRateBinPerSec.ValueChangedFcn = createCallbackFcn(app, @SamplingRateBinPerSecValueChanged, true);
            app.SamplingRatessecLabel = uilabel(app.GridLayout3);
            app.SamplingRatessecLabel.Text = {'Sampling Rate'; '(s/sec)'};
            app.WindowBin = uieditfield(app.GridLayout3, 'numeric');
            app.WindowBin.LowerLimitInclusive = 'off';
            app.WindowBin.UpperLimitInclusive = 'off';
            app.WindowBin.Limits = [0 Inf];
            app.WindowBin.ValueDisplayFormat = '%.0f';
            app.WindowBin.ValueChangedFcn = createCallbackFcn(app, @WindowBinValueChanged, true);
            app.WindowBin.Value = 1;
            app.OverlapBin = uieditfield(app.GridLayout3, 'numeric');
            app.OverlapBin.LowerLimitInclusive = 'off';
            app.OverlapBin.UpperLimitInclusive = 'off';
            app.OverlapBin.Limits = [0 Inf];
            app.OverlapBin.ValueDisplayFormat = '%.0f';
            app.OverlapBin.ValueChangedFcn = createCallbackFcn(app, @OverlapBinValueChanged, true);
            app.OverlapBin.Enable = 'off';
            app.OverlapBin.Value = 1;
            app.FFTwinBin = uieditfield(app.GridLayout3, 'numeric');
            app.FFTwinBin.LowerLimitInclusive = 'off';
            app.FFTwinBin.UpperLimitInclusive = 'off';
            app.FFTwinBin.Limits = [0 Inf];
            app.FFTwinBin.ValueDisplayFormat = '%.0f';
            app.FFTwinBin.ValueChangedFcn = createCallbackFcn(app, @FFTwinBinValueChanged, true);
            app.FFTwinBin.Enable = 'off';
            app.FFTwinBin.Value = 1;
            app.OverlapManualButton = uibutton(app.GridLayout3, 'state');
            app.OverlapManualButton.ValueChangedFcn = createCallbackFcn(app, @OverlapManualButtonValueChanged, true);
            app.OverlapManualButton.Text = 'Overlap (ms-bins)';
            app.FFTManualButton = uibutton(app.GridLayout3, 'state');
            app.FFTManualButton.ValueChangedFcn = createCallbackFcn(app, @FFTManualButtonValueChanged, true);
            app.FFTManualButton.Text = 'FFTwin (ms-bins)';
            app.GridVisualizationsControls = uigridlayout(app.GridLayout2);
            app.CLimEndSlider = uislider(app.GridVisualizationsControls);
            app.CLimEndSlider.ValueChangedFcn = createCallbackFcn(app, @CLimEndSliderValueChanged, true);
            app.CLimStartEditField = uieditfield(app.GridVisualizationsControls, 'numeric');
            app.CLimStartEditField.ValueChangedFcn = createCallbackFcn(app, @CLimStartEditFieldValueChanged, true);
            app.CLimEndEditField = uieditfield(app.GridVisualizationsControls, 'numeric');
            app.CLimEndEditField.ValueChangedFcn = createCallbackFcn(app, @CLimEndEditFieldValueChanged, true);
            app.CLimLabel = uilabel(app.GridVisualizationsControls);
            app.CLimLabel.Text = 'CLim';
            app.CLimStartSlider = uislider(app.GridVisualizationsControls);
            app.CLimStartSlider.ValueChangedFcn = createCallbackFcn(app, @CLimStartSliderValueChanged, true);
            app.lowerlimLabel = uilabel(app.GridVisualizationsControls);
            app.lowerlimLabel.Text = 'lowerlim';
            app.upperlimLabel = uilabel(app.GridVisualizationsControls);
            app.upperlimLabel.Text = 'upperlim';
            app.ManualCLimCheckBox = uicheckbox(app.GridVisualizationsControls);
            app.ManualCLimCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualCLimCheckBoxValueChanged, true);
            app.ManualCLimCheckBox.Text = 'Manual CLim';
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.TriggerTimeLabel = uilabel(app.GridLayout7);
            app.TriggerTimeLabel.Text = {'Trigger'; 'Time'};
            app.TriggerTimeEditField = uieditfield(app.GridLayout7, 'numeric');
            app.TimeStartEditField = uieditfield(app.GridLayout7, 'numeric');
            app.TimeStartEditField.Limits = [0 Inf];
            app.TimeStartEditField.ValueChangedFcn = createCallbackFcn(app, @TimeStartEditFieldValueChanged, true);
            app.TimeEndEditField = uieditfield(app.GridLayout7, 'numeric');
            app.TimeEndEditField.Limits = [0 Inf];
            app.TimeEndEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEndEditFieldValueChanged, true);
            app.TimeLabel = uilabel(app.GridLayout7);
            app.TimeLabel.Text = 'Time';
            app.ResetCLinButton = uibutton(app.GridLayout7, 'push');
            app.ResetCLinButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCLinButtonPushed, true);
            app.ResetCLinButton.Text = 'Reset';
            app.ReplotButton = uibutton(app.GridLayout7, 'push');
            app.ReplotButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotButtonPushed, true);
            app.ReplotButton.Text = 'Replot';
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.MaxFrequencyHzLabel = uilabel(app.GridLayout8);
            app.MaxFrequencyHzLabel.Text = 'Max Frequency(Hz)';
            app.MaxFrequencyHzEditField = uieditfield(app.GridLayout8, 'numeric');
            app.MaxFrequencyHzEditField.Limits = [0 125000];
            app.MaxFrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.MaxFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @MaxFrequencyHzEditFieldValueChanged, true);
            app.MinFrequencyHzLabel = uilabel(app.GridLayout8);
            app.MinFrequencyHzLabel.Text = 'Min Frequency(Hz)';
            app.MinFrequencyHzEditField = uieditfield(app.GridLayout8, 'numeric');
            app.MinFrequencyHzEditField.Limits = [0 125000];
            app.MinFrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.MinFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @MinFrequencyHzEditFieldValueChanged, true);
            app.MinFrequencyHzEditField.Value = 1;
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.ResampleButton = uibutton(app.GridLayout9, 'push');
            app.ResampleButton.ButtonPushedFcn = createCallbackFcn(app, @ResampleButtonPushed, true);
            app.ResampleButton.Text = 'Resample';
            app.ResampleEditField = uieditfield(app.GridLayout9, 'numeric');
            app.ResampleEditField.LowerLimitInclusive = 'off';
            app.ResampleEditField.UpperLimitInclusive = 'off';
            app.ResampleEditField.Limits = [0 Inf];
            app.ResampleEditField.ValueDisplayFormat = '%.0f';
            app.ResampleEditField.Value = 1;
            app.NewSamplingRateLabel = uilabel(app.GridLayout9);
            app.NewSamplingRateLabel.Text = 'New Sampling Rate';
            app.VisualizationPanel = uipanel(app.GridLayout);
            app.GridAxes = uigridlayout(app.VisualizationPanel);
            app.axSpect = uiaxes(app.GridAxes);
            title(app.axSpect, '')
            xlabel(app.axSpect, 'Time (Sec)')
            ylabel(app.axSpect, 'Frequency (kHz)')
            app.axP = uiaxes(app.GridAxes);
            title(app.axP, '')
            xlabel(app.axP, 'A.U')
            ylabel(app.axP, 'Time (Sec)')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout6 = uigridlayout(app.Panel);
            app.GridLDestination = uigridlayout(app.GridLayout6);
            app.GridLayout5 = uigridlayout(app.GridLayout6);
            app.GridSize = uigridlayout(app.GridLayout6);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = splitterEditorApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end