classdef fix_missing_electro_files_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        RunButton                matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        CreateMissingFilesLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        config_tab % Config table
    end
    
    methods (Access = public)
        function tab = get_tab_template(app)
             var_names = {...
                    'number',...
                    'folder_name',...
                    'mini_file_name',...
                    'mini_file_sufix',...
                    'file_name',...
                    'main_file_sufix',...
                    'save_folder_name',...
                    'stimulus_insertion_frame',...
                    'stimulus_removal_frame',...
                    'start_trigger_time_in_sec',...
                    'total_number_of_frames',...
                    'legnth_of_audio_in_sec',...
                    'video_fps'};
                
                var_types = {...
                   'double',...
                   'string',...
                   'string',...
                   'string',...
                   'string',...
                   'string',...
                   'string',...
                   'double',...
                   'double',...
                   'double',...
                   'double',...
                   'double',...                  
                   'double',...
                };
                
                tab = table('Size',[0,13],...
                   'VariableNames',var_names,...
                  'VariableTypes',var_types );
        end
        function update_config_tab(app,tab)
            tab_template = get_tab_template(app);
            if isempty(tab)
               app.config_tab = tab_template;  
            else
                app.config_tab = [tab_template;tab];  
            end
            app.UITable.ColumnName = app.config_tab.Properties.VariableNames;
            app.UITable.Data = app.config_tab;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_config_tab(app,[])
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('.xlsx','Select a config table');
            
            if isequal(file,0)
                disp('User selected Cancel');
            else
                tab =  readtable(fullfile(path,file));
                app.update_config_tab(tab);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            tab = app.config_tab;
            if isempty(tab)
                return
            end
            fix_missing_electro_files(tab);   
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            tab = app.config_tab;
            tab(indices) = newData;
            app.update_config_tab(tab);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CreateMissingFilesLabel = uilabel(app.GridLayout);
            app.CreateMissingFilesLabel.Text = 'Create Missing Files';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fix_missing_electro_files_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end